classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PersonalizarRedeTab            matlab.ui.container.Tab
        TesteEditField                 matlab.ui.control.NumericEditField
        TesteEditFieldLabel            matlab.ui.control.Label
        ValidacaoEditField             matlab.ui.control.NumericEditField
        ValidaoEditFieldLabel          matlab.ui.control.Label
        TreinoEditField                matlab.ui.control.NumericEditField
        TreinoEditFieldLabel           matlab.ui.control.Label
        ParametrosLabel                matlab.ui.control.Label
        DatasetDropDown                matlab.ui.control.DropDown
        DatasetDropDownLabel           matlab.ui.control.Label
        TreinarButton                  matlab.ui.control.Button
        OutputTextArea                 matlab.ui.control.TextArea
        OutputTextAreaLabel            matlab.ui.control.Label
        AtivacaoDropDown               matlab.ui.control.DropDown
        FunodeAtivaoSadaDropDownLabel  matlab.ui.control.Label
        TreinoDropDown                 matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel      matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        NCamadasSlider                 matlab.ui.control.Slider
        NCamadasSliderLabel            matlab.ui.control.Label
        CarregarRedeTab                matlab.ui.container.Tab
        ExecutarButton                 matlab.ui.control.Button
        UITable_2                      matlab.ui.control.Table
        OutputTextArea_2               matlab.ui.control.TextArea
        OutputTextArea_2Label          matlab.ui.control.Label
        DatasetDropDown_2              matlab.ui.control.DropDown
        DatasetDropDown_2Label         matlab.ui.control.Label
        RedesDisponiveisListBox        matlab.ui.control.ListBox
        RedesDisponiveisListBoxLabel   matlab.ui.control.Label
        ImportarRedeButton             matlab.ui.control.Button
        CaminhodaRedeEditField         matlab.ui.control.EditField
        CaminhodaRedeEditFieldLabel    matlab.ui.control.Label
        CasoParticularTab              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            funcoesTreino = {'trainbfg', 'trainbfgc', 'trainbr', 'trainbu', 'trainc', ...
                'traincgb', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', ...
                'traingdx', 'trainlm', 'trainoss', 'trainr', 'trainrp', 'trainru', 'trains', 'trainscg'};

            app.TreinoDropDown.Items = funcoesTreino;
            app.TreinoDropDown.Value = funcoesTreino{13}; % default = trainlm

            funcoesAtivacao = {'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', ...
                   'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', ...
                   'satlins', 'softmax', 'tansig', 'tribas'};
            
            app.AtivacaoDropDown.Items = funcoesAtivacao;
            app.AtivacaoDropDown.Value = funcoesAtivacao{7}; % default = purelin

            table = cell(1, 3);
            table{1, 1} = 1; % Coluna 'Camada'
            table{1, 2} = []; % Coluna 'Nº Neurônios', vazia por padrão
            table{1, 3} = ''; % Coluna 'Função de Ativação', vazia por padrão
            app.UITable.Data = table;
            app.UITable.ColumnFormat = {'numeric', 'numeric', funcoesAtivacao};

            uialert(app.UIFigure, 'Bem Vindo! A rede é treinada 10 vezes e o output é a média dos treinos.', 'Informação de Inicialização', 'Icon', 'info');
           
        end

        % Value changed function: NCamadasSlider
        function NCamadasSliderValueChanged(app, event)
            % Obtém o valor atual do slider e arredonda para o inteiro mais próximo
            numberOfLayers = round(app.NCamadasSlider.Value);
            app.NCamadasSlider.Value = numberOfLayers;
        
            % Inicializa um cell array vazio para os dados da tabela
            tableData = cell(numberOfLayers, 3);

            % Lista das funções de ativação disponíveis
            funcoesAtivacao = {'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', ...
                   'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', ...
                   'satlins', 'softmax', 'tansig', 'tribas'};

        
            % Preenche a primeira coluna do cell array com o número da camada
            for i = 1:numberOfLayers
                tableData{i, 1} = i; % Coluna 'Camada'
                tableData{i, 2} = []; % Coluna 'Nº Neurônios', vazia por padrão
                tableData{i, 3} = ''; % Coluna 'Função de Ativação', vazia por padrão
            end
        
            % Define os nomes das colunas da tabela
            %app.UITable.ColumnName = {'Camada', 'Nº Neurônios', 'Função de Ativação'};
            % Define as colunas como editáveis
            %app.UITable.ColumnEditable = [false, true, true]; 
        
            % Atualiza os dados na tabela
            app.UITable.Data = tableData;
        
            % Se necessário, defina os formatos de coluna após a definição dos dados
            app.UITable.ColumnFormat = {'numeric', 'numeric', funcoesAtivacao};

        end

        % Button pushed function: TreinarButton
        function TreinarButtonPushed(app, event)
            if isempty(app.UITable.Data)
                uialert(app.UIFigure, 'A tabela está vazia.', 'Erro de Entrada');
                return;
            end
            
            % verificar coluna 'N neuronios' da tabela
            linhas = size(app.UITable.Data, 1);
            for i = 1:linhas
                if isempty(app.UITable.Data{i, 2}) || isnan(app.UITable.Data{i, 2})
                    uialert(app.UIFigure, 'Por favor, insira um número válido de neurônios para todas as camadas.', 'Erro de Entrada', 'Icon', 'error');
                    return;
                end
            end
            
            % Vverificar campos da tabela de funcao de ativacao
            for i = 1:size(app.UITable.Data, 1)
                if isempty(app.UITable.Data{i, 3})
                    uialert(app.UIFigure, 'Por favor, selecione uma função de ativação para todas as camadas.', 'Erro de Entrada', 'Icon', 'error');
                    return;
                end
            end
        
            % verificar parametros (treino, validacao, teste)
            trainRatio = app.TreinoEditField.Value;
            valRatio = app.ValidacaoEditField.Value;
            testRatio = app.TesteEditField.Value;
            if isnan(trainRatio) || isnan(valRatio) || isnan(testRatio) || (trainRatio + valRatio + testRatio) ~= 1
                uialert(app.UIFigure, 'As proporções de treino, validação e teste devem somar 1.', 'Erro de Entrada', 'Icon', 'error');
                return;
            end
            
            
            treinarRede(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PersonalizarRedeTab = uitab(app.TabGroup);
            app.PersonalizarRedeTab.Title = 'Personalizar Rede';
            app.NCamadasSliderLabel = uilabel(app.PersonalizarRedeTab);
            app.NCamadasSliderLabel.Text = 'Nº Camadas';
            app.NCamadasSlider = uislider(app.PersonalizarRedeTab);
            app.NCamadasSlider.Limits = [1 5];
            app.NCamadasSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NCamadasSlider.ValueChangedFcn = createCallbackFcn(app, @NCamadasSliderValueChanged, true);
            app.NCamadasSlider.MinorTicks = [];
            app.NCamadasSlider.Value = 1;
            app.UITable = uitable(app.PersonalizarRedeTab);
            app.UITable.ColumnName = {'Camada'; 'Nº Neurónios'; 'Função Ativação'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.FunodeTreinoDropDownLabel = uilabel(app.PersonalizarRedeTab);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino';
            app.TreinoDropDown = uidropdown(app.PersonalizarRedeTab);
            app.TreinoDropDown.Items = {};
            app.TreinoDropDown.Value = {};
            app.FunodeAtivaoSadaDropDownLabel = uilabel(app.PersonalizarRedeTab);
            app.FunodeAtivaoSadaDropDownLabel.Text = 'Função de Ativação (Saída)';
            app.AtivacaoDropDown = uidropdown(app.PersonalizarRedeTab);
            app.AtivacaoDropDown.Items = {};
            app.AtivacaoDropDown.Value = {};
            app.OutputTextAreaLabel = uilabel(app.PersonalizarRedeTab);
            app.OutputTextAreaLabel.Text = 'Output :';
            app.OutputTextArea = uitextarea(app.PersonalizarRedeTab);
            app.TreinarButton = uibutton(app.PersonalizarRedeTab, 'push');
            app.TreinarButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarButtonPushed, true);
            app.TreinarButton.Text = 'Treinar';
            app.DatasetDropDownLabel = uilabel(app.PersonalizarRedeTab);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.PersonalizarRedeTab);
            app.DatasetDropDown.Items = {'Test', 'Train', 'Start'};
            app.DatasetDropDown.Value = 'Test';
            app.ParametrosLabel = uilabel(app.PersonalizarRedeTab);
            app.ParametrosLabel.Text = 'Parametros:';
            app.TreinoEditFieldLabel = uilabel(app.PersonalizarRedeTab);
            app.TreinoEditFieldLabel.Text = 'Treino';
            app.TreinoEditField = uieditfield(app.PersonalizarRedeTab, 'numeric');
            app.TreinoEditField.Limits = [0 1];
            app.TreinoEditField.Value = 0.7;
            app.ValidaoEditFieldLabel = uilabel(app.PersonalizarRedeTab);
            app.ValidaoEditFieldLabel.Text = 'Validação';
            app.ValidacaoEditField = uieditfield(app.PersonalizarRedeTab, 'numeric');
            app.ValidacaoEditField.Limits = [0 1];
            app.ValidacaoEditField.Value = 0.15;
            app.TesteEditFieldLabel = uilabel(app.PersonalizarRedeTab);
            app.TesteEditFieldLabel.Text = 'Teste';
            app.TesteEditField = uieditfield(app.PersonalizarRedeTab, 'numeric');
            app.TesteEditField.Limits = [0 1];
            app.TesteEditField.Value = 0.15;
            app.CarregarRedeTab = uitab(app.TabGroup);
            app.CarregarRedeTab.Title = 'Carregar Rede';
            app.CaminhodaRedeEditFieldLabel = uilabel(app.CarregarRedeTab);
            app.CaminhodaRedeEditFieldLabel.Text = 'Caminho da Rede';
            app.CaminhodaRedeEditField = uieditfield(app.CarregarRedeTab, 'text');
            app.CaminhodaRedeEditField.Editable = 'off';
            app.ImportarRedeButton = uibutton(app.CarregarRedeTab, 'push');
            app.ImportarRedeButton.Text = 'Importar Rede';
            app.RedesDisponiveisListBoxLabel = uilabel(app.CarregarRedeTab);
            app.RedesDisponiveisListBoxLabel.Text = 'Redes Disponiveis';
            app.RedesDisponiveisListBox = uilistbox(app.CarregarRedeTab);
            app.DatasetDropDown_2Label = uilabel(app.CarregarRedeTab);
            app.DatasetDropDown_2Label.Text = 'Dataset';
            app.DatasetDropDown_2 = uidropdown(app.CarregarRedeTab);
            app.DatasetDropDown_2.Items = {'Test', 'Train', 'Start'};
            app.DatasetDropDown_2.Value = 'Test';
            app.OutputTextArea_2Label = uilabel(app.CarregarRedeTab);
            app.OutputTextArea_2Label.Text = 'Output :';
            app.OutputTextArea_2 = uitextarea(app.CarregarRedeTab);
            app.UITable_2 = uitable(app.CarregarRedeTab);
            app.UITable_2.ColumnName = {'Camada'; 'Nº Neurónios'; 'Função Ativação'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false true true];
            app.ExecutarButton = uibutton(app.CarregarRedeTab, 'push');
            app.ExecutarButton.Text = 'Executar';
            app.CasoParticularTab = uitab(app.TabGroup);
            app.CasoParticularTab.Title = 'Caso Particular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end